classdef meanSmooth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        ApplyButton           matlab.ui.control.Button
        kvalueEditFieldLabel  matlab.ui.control.Label
        kvalueEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        signal; % Noisy signal
        n; % size of time
        time;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function onStart(app)
            srate = 1000;
            app.time = 0 : 1/srate : 2;
            app.n = length(app.time);
            p = 15;
            
            noiseamp = 5;
            
            ampl = interp1(rand(p,1)*30, linspace(1,p,app.n));
            noise = noiseamp*randn(size(app.time));
            
            app.signal = ampl + noise;
            
            plot(app.UIAxes, app.time, app.signal);
        end

        % Button pushed function: ApplyButton
        function applyFiltering(app, event)
            cla(app.UIAxes2);
            filteredSignal = zeros(size(app.signal));
            k = app.kvalueEditField.Value;
            for i = k+1 : app.n - k - 1
                filteredSignal(i) = mean(app.signal(i-k:i+k));
            end
            plot(app.UIAxes2, app.time, filteredSignal);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Noisy Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Mean Smoothed')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @applyFiltering, true);
            app.ApplyButton.Text = 'Apply';
            app.kvalueEditFieldLabel = uilabel(app.UIFigure);
            app.kvalueEditFieldLabel.Text = 'k value';
            app.kvalueEditField = uieditfield(app.UIFigure, 'text');
            app.kvalueEditField.Value = '2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = meanSmooth

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end